classdef ProjectileMotionVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        gEditField                   matlab.ui.control.NumericEditField
        gEditFieldLabel              matlab.ui.control.Label
        CustomButton                 matlab.ui.control.StateButton
        HoldonButton                 matlab.ui.control.StateButton
        MoonButton                   matlab.ui.control.StateButton
        StartingYEditField           matlab.ui.control.NumericEditField
        StartingYEditFieldLabel      matlab.ui.control.Label
        StartingXEditField           matlab.ui.control.NumericEditField
        StartingXEditFieldLabel      matlab.ui.control.Label
        NeptuneButton                matlab.ui.control.StateButton
        UranusButton                 matlab.ui.control.StateButton
        SaturnButton                 matlab.ui.control.StateButton
        JupiterButton                matlab.ui.control.StateButton
        MarsButton                   matlab.ui.control.StateButton
        EarthButton                  matlab.ui.control.StateButton
        VenusButton                  matlab.ui.control.StateButton
        MercuryButton                matlab.ui.control.StateButton
        SunButton                    matlab.ui.control.StateButton
        ResetButton                  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        msLabel                      matlab.ui.control.Label
        SimulateButton               matlab.ui.control.Button
        ThrowingangleEditField       matlab.ui.control.NumericEditField
        ThrowingangleEditFieldLabel  matlab.ui.control.Label
        StartingspeedEditField       matlab.ui.control.NumericEditField
        StartingspeedEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        gravities = struct('Sun', 274.68, 'Mercury', 3.70, 'Venus', 8.87, ...
                           'Earth', 9.81, 'Mars', 3.69, 'Jupiter', 23.12, ...
                           'Saturn', 8.96, 'Uranus', 8.69, 'Neptune', 11.15, ...
                           'Moon', 1.62);
    end
    
    methods (Access = private)

        function plotTrajectory(app, planetName)
            if app.CustomButton.Value
                gravity = app.gEditField.Value;
            else
                gravity = app.gravities.(planetName);
            end
            
            v0 = app.StartingspeedEditField.Value;
            angle = deg2rad(app.ThrowingangleEditField.Value);
            x0 = app.StartingXEditField.Value;
            y0 = app.StartingYEditField.Value;
        
            v0x = v0 * cos(angle);
            v0y = v0 * sin(angle);
        
            t_flight = (v0y + sqrt(v0y^2 + 2 * gravity * y0)) / gravity;
            t = linspace(0, t_flight, 100);
            x = x0 + v0x * t;
            y = y0 + v0y * t - 0.5 * gravity * t.^2;q
            
            max_height = max(y);
            max_index = find(y == max_height, 1);
            max_distance = x(max_index);
            
            range_distance = x(end);
            
            plot(app.UIAxes, x, y, 'DisplayName', planetName, 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            
            plot(app.UIAxes, max_distance, max_height, 'rx', 'MarkerSize', 8, 'DisplayName', 'Maximální výška');
            
            text(app.UIAxes, max_distance, max_height, ...
                 sprintf('Height: %.2f m\nDistance: %.2f m', max_height, abs(max_distance - x0)), ...
                 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', ...
                 'Color', 'red', 'FontSize', 10);
            
            plot(app.UIAxes, range_distance, 0, 'gx', 'MarkerSize', 8, 'DisplayName', 'Dolet');
            
            text(app.UIAxes, range_distance, 0, ...
                 sprintf('Range: %.2f m', abs(range_distance - x0)), ...
                 'VerticalAlignment', 'top', 'HorizontalAlignment', 'center', ...
                 'Color', 'green', 'FontSize', 10);
        end

        function redrawTrajectories(app)
            if ~app.HoldonButton.Value, cla(app.UIAxes); end
            
            if app.SunButton.Value, app.plotTrajectory('Sun'); end
            if app.MercuryButton.Value, app.plotTrajectory('Mercury'); end
            if app.VenusButton.Value, app.plotTrajectory('Venus'); end
            if app.EarthButton.Value, app.plotTrajectory('Earth'); end
            if app.MarsButton.Value, app.plotTrajectory('Mars'); end
            if app.JupiterButton.Value, app.plotTrajectory('Jupiter'); end
            if app.SaturnButton.Value, app.plotTrajectory('Saturn'); end
            if app.UranusButton.Value, app.plotTrajectory('Uranus'); end
            if app.NeptuneButton.Value, app.plotTrajectory('Neptune'); end
            if app.MoonButton.Value, app.plotTrajectory('Moon'); end
            if app.CustomButton.Value, app.plotTrajectory('Custom'); end

            legend(app.UIAxes, 'show');
            xlabel(app.UIAxes, 'Distance (m)');
            ylabel(app.UIAxes, 'Height (m)');
            grid(app.UIAxes, 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.redrawTrajectories();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            xlabel(app.UIAxes, 'Distance (m)');
            ylabel(app.UIAxes, 'Height (m)');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, 'off');

            xlim(app.UIAxes, 'auto');
            ylim(app.UIAxes, 'auto');

            app.HoldonButton.Value = true;

            app.gEditField.Enable = "off";
            app.gEditFieldLabel.Enable = "off";
            app.SunButton.Value = true;
            app.MercuryButton.Value = false;
            app.VenusButton.Value = false;
            app.EarthButton.Value = false;
            app.MarsButton.Value = false;
            app.JupiterButton.Value = false;
            app.SaturnButton.Value = false;
            app.UranusButton.Value = false;
            app.NeptuneButton.Value = false;
            app.MoonButton.Value = false;
            app.CustomButton.Value = false;

            app.StartingXEditField.Value = 0;
            app.StartingYEditField.Value = 0;
            app.StartingspeedEditField.Value = 1;
            app.ThrowingangleEditField.Value = 1;
        end

        % Value changed function: SunButton
        function SunButtonValueChanged(app, event)
            value = app.SunButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: EarthButton
        function EarthButtonValueChanged(app, event)
            value = app.EarthButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: SaturnButton
        function SaturnButtonValueChanged(app, event)
            value = app.SaturnButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: MercuryButton
        function MercuryButtonValueChanged(app, event)
            value = app.MercuryButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: MarsButton
        function MarsButtonValueChanged(app, event)
            value = app.MarsButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: UranusButton
        function UranusButtonValueChanged(app, event)
            value = app.UranusButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: VenusButton
        function VenusButtonValueChanged(app, event)
            value = app.VenusButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: JupiterButton
        function JupiterButtonValueChanged(app, event)
            value = app.JupiterButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: NeptuneButton
        function NeptuneButtonValueChanged(app, event)
            value = app.NeptuneButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.MoonButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: MoonButton
        function MoonButtonValueChanged(app, event)
            value = app.MoonButton.Value;
            if value
                app.gEditField.Enable = "off";
                app.gEditFieldLabel.Enable = "off";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.CustomButton.Value = false;
            end
        end

        % Value changed function: CustomButton
        function CustomButtonValueChanged(app, event)
            value = app.CustomButton.Value;
            if value
                app.gEditField.Enable = "on";
                app.gEditFieldLabel.Enable = "on";
                app.SunButton.Value = false;
                app.MercuryButton.Value = false;
                app.VenusButton.Value = false;
                app.EarthButton.Value = false;
                app.MarsButton.Value = false;
                app.JupiterButton.Value = false;
                app.SaturnButton.Value = false;
                app.UranusButton.Value = false;
                app.NeptuneButton.Value = false;
                app.MoonButton.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile motion')
            xlabel(app.UIAxes, 'Distance (m)')
            ylabel(app.UIAxes, 'Height (m)')
            zlabel(app.UIAxes, 'Z')
            app.StartingspeedEditFieldLabel = uilabel(app.UIFigure);
            app.StartingspeedEditFieldLabel.Text = 'Starting speed';
            app.StartingspeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingspeedEditField.Limits = [1 Inf];
            app.StartingspeedEditField.Value = 1;
            app.ThrowingangleEditFieldLabel = uilabel(app.UIFigure);
            app.ThrowingangleEditFieldLabel.Text = 'Throwing angle';
            app.ThrowingangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThrowingangleEditField.Limits = [1 179];
            app.ThrowingangleEditField.Value = 1;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SunButton = uibutton(app.UIFigure, 'state');
            app.SunButton.ValueChangedFcn = createCallbackFcn(app, @SunButtonValueChanged, true);
            app.SunButton.Text = 'Sun';
            app.SunButton.Value = true;
            app.MercuryButton = uibutton(app.UIFigure, 'state');
            app.MercuryButton.ValueChangedFcn = createCallbackFcn(app, @MercuryButtonValueChanged, true);
            app.MercuryButton.Text = 'Mercury';
            app.VenusButton = uibutton(app.UIFigure, 'state');
            app.VenusButton.ValueChangedFcn = createCallbackFcn(app, @VenusButtonValueChanged, true);
            app.VenusButton.Text = 'Venus';
            app.EarthButton = uibutton(app.UIFigure, 'state');
            app.EarthButton.ValueChangedFcn = createCallbackFcn(app, @EarthButtonValueChanged, true);
            app.EarthButton.Text = 'Earth';
            app.MarsButton = uibutton(app.UIFigure, 'state');
            app.MarsButton.ValueChangedFcn = createCallbackFcn(app, @MarsButtonValueChanged, true);
            app.MarsButton.Text = 'Mars';
            app.JupiterButton = uibutton(app.UIFigure, 'state');
            app.JupiterButton.ValueChangedFcn = createCallbackFcn(app, @JupiterButtonValueChanged, true);
            app.JupiterButton.Text = 'Jupiter';
            app.SaturnButton = uibutton(app.UIFigure, 'state');
            app.SaturnButton.ValueChangedFcn = createCallbackFcn(app, @SaturnButtonValueChanged, true);
            app.SaturnButton.Text = 'Saturn';
            app.UranusButton = uibutton(app.UIFigure, 'state');
            app.UranusButton.ValueChangedFcn = createCallbackFcn(app, @UranusButtonValueChanged, true);
            app.UranusButton.Text = 'Uranus';
            app.NeptuneButton = uibutton(app.UIFigure, 'state');
            app.NeptuneButton.ValueChangedFcn = createCallbackFcn(app, @NeptuneButtonValueChanged, true);
            app.NeptuneButton.Text = 'Neptune';
            app.StartingXEditFieldLabel = uilabel(app.UIFigure);
            app.StartingXEditFieldLabel.Text = 'Starting X';
            app.StartingXEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingYEditFieldLabel = uilabel(app.UIFigure);
            app.StartingYEditFieldLabel.Text = 'Starting Y';
            app.StartingYEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingYEditField.Limits = [0 Inf];
            app.MoonButton = uibutton(app.UIFigure, 'state');
            app.MoonButton.ValueChangedFcn = createCallbackFcn(app, @MoonButtonValueChanged, true);
            app.MoonButton.Text = 'Moon';
            app.HoldonButton = uibutton(app.UIFigure, 'state');
            app.HoldonButton.Text = 'Hold on';
            app.HoldonButton.Value = true;
            app.CustomButton = uibutton(app.UIFigure, 'state');
            app.CustomButton.ValueChangedFcn = createCallbackFcn(app, @CustomButtonValueChanged, true);
            app.CustomButton.Text = 'Custom';
            app.gEditFieldLabel = uilabel(app.UIFigure);
            app.gEditFieldLabel.Enable = 'off';
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.UIFigure, 'numeric');
            app.gEditField.Limits = [0.001 Inf];
            app.gEditField.Enable = 'off';
            app.gEditField.Value = 0.001;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectileMotionVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end